classdef asc_hard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Label_18              matlab.ui.control.Label
        Label_17              matlab.ui.control.Label
        Label_16              matlab.ui.control.Label
        Label_15              matlab.ui.control.Label
        Label_14              matlab.ui.control.Label
        Label_13              matlab.ui.control.Label
        Label_12              matlab.ui.control.Label
        Label_11              matlab.ui.control.Label
        Label_10              matlab.ui.control.Label
        Label_9               matlab.ui.control.Label
        Label_8               matlab.ui.control.Label
        Label_7               matlab.ui.control.Label
        Label_6               matlab.ui.control.Label
        Label_5               matlab.ui.control.Label
        Label_4               matlab.ui.control.Label
        Label_3               matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        AlternateButton       matlab.ui.control.Button
        AscendingButton       matlab.ui.control.Button
        DescendingButton      matlab.ui.control.Button
        CMajorCheckBox        matlab.ui.control.CheckBox
        GMajorCheckBox        matlab.ui.control.CheckBox
        Button17              matlab.ui.control.Button
        Button16              matlab.ui.control.Button
        Button15              matlab.ui.control.Button
        Button14              matlab.ui.control.Button
        Button13              matlab.ui.control.Button
        Button12              matlab.ui.control.Button
        Button11              matlab.ui.control.Button
        Button10              matlab.ui.control.Button
        Button9               matlab.ui.control.Button
        Button8               matlab.ui.control.Button
        Button7               matlab.ui.control.Button
        Button6               matlab.ui.control.Button
        Button5               matlab.ui.control.Button
        Button4               matlab.ui.control.Button
        Button2               matlab.ui.control.Button
        Button3               matlab.ui.control.Button
        Button1               matlab.ui.control.Button
        TabsButton            matlab.ui.control.Button
        HomeButton            matlab.ui.control.Button
        KeySetsLabel          matlab.ui.control.Label
        KeyArrangementsLabel  matlab.ui.control.Label
        Label                 matlab.ui.control.Label
        Button_2              matlab.ui.control.Button
        Button                matlab.ui.control.Button
        DigitalKalimbaLabel   matlab.ui.control.Label
        TextArea              matlab.ui.control.TextArea
    end

    
      properties (Access = public)
       CMajorFiles string
       GMajorFiles string
       CurrentFiles string
       audioplayerObj
   
    end
    methods (Access = private)
        
        function updateButtons(app)
      
            if app.CMajorCheckBox.Value
                app.CurrentFiles = app.CMajorFiles;
                app.Button1.Text = "1-C4";
                app.Button2.Text = '2-D4';
                app.Button3.Text = '3-E4';
                app.Button4.Text = '4-F4';
                app.Button5.Text = '5-G4';
                app.Button6.Text = '6-A4';
                app.Button7.Text = '7-B4';
                app.Button8.Text = '1*-C5';
                app.Button9.Text = '2*-D5';
                app.Button10.Text = '3*-E5';
                app.Button11.Text = '4*-F5';
                app.Button12.Text = '5*-G5';
                app.Button13.Text = '6*-A5';
                app.Button14.Text = '7*-B5';
                app.Button15.Text = '1**-C6';
                app.Button16.Text = '2**-D6';
                app.Button17.Text = '3**-E6';
            elseif app.GMajorCheckBox.Value
                app.Button1.Text ='1-G4';
                app.Button2.Text ='2-A4';
                app.Button3.Text ='3-B4';
                app.Button4.Text ='4-C5';
                app.Button5.Text ='5-D5';
                app.Button6.Text ='6-E5';
                app.Button7.Text ='7-F#5';
                app.Button8.Text ='1*-G5';
                app.Button9.Text ='2*-A5';
                app.Button10.Text ='3*-B5';
                app.Button11.Text ='4*-C6';
                app.Button12.Text ='5*-D6';
                app.Button13.Text ='6*-E6';
                app.Button14.Text ='7*-F#6';
                app.Button15.Text ='1**-G6';
                app.Button16.Text ='2**-A6';
                app.Button17.Text ='3**-B6';
            end
        end


        end
   
    methods (Access = public)
        
        function onKeyPress(app,event)
            key = event.Key;
        switch key
                case 'p'
                    Button1Pushed(app, event);
                case 'o'
                    Button2Pushed(app, event);
                case 'i'
                    Button3Pushed(app, event);
                case 'u'
                    Button4Pushed(app, event);
                case 'y'
                    Button5Pushed(app, event);
                case 't'
                    Button6Pushed(app, event);
                case 'r'
                    Button7Pushed(app, event);
                case 'e'
                    Button8Pushed(app, event);
                case 'w'
                    Button9Pushed(app, event);
                case 'q'
                    Button10Pushed(app, event);
                case 'l'
                    Button11Pushed(app, event);
                case 'k'
                    Button12Pushed(app, event);
                case 'j'
                    Button13Pushed(app, event);
                case 'h'
                    Button14Pushed(app, event);
                case 'g'
                    Button15Pushed(app, event);
                case 'f'
                    Button16Pushed(app, event);
                case 'd'
                    Button17Pushed(app, event);
                
        end
            
        end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CMajorFiles = ["1 C4 - Copy.MP3", "2 C4.MP3" , "3 E4.MP3", "4 F4.MP3", "5 G4.MP3", "6 A4.MP3", "7 B4.MP3", "1^ C5.MP3", "2^ D5.MP3", "3^ E5.MP3", "4^ F5.MP3", "5^ G5.MP3", "6^ A5.MP3", "7^ B5.MP3", "1^^ C6.MP3", "2^^ D6.MP3", "3^^ E6.MP3"];
            app.GMajorFiles = ["G -1-G4.MP3", "G - 2-A4.MP3", "G - 3-B4.MP3", "G - 4-C4.MP3", "G - 5-D5.MP3", "G - 6-E5.MP3", "G - 7-F#5.MP3", "G - 1^-G5.MP3", "G - 2^-A5.MP3", "G - 3^-B5.MP3", "G - 4^-C6.MP3", "G - 5^-D6.MP3", "G - 6^-E6.MP3", "G - 7^-F#6.MP3", "G - 1^^-G6.MP3", "G - 2^^-A6.MP3", "G - 3^^-B6.MP3"];
            app.CMajorCheckBox.Value = true;
            app.GMajorCheckBox.Value = false;
            
            app.CurrentFiles = app.CMajorFiles;
            updateButtons(app);
            app.UIFigure.KeyPressFcn = @(src, event) onKeyPress(app, event);


        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            if app.CMajorCheckBox.Value
                [y,fs] = audioread(app.CMajorFiles(1));
                sound(y,fs);
            else 
                [y,fs] = audioread(app.GMajorFiles(1));
                sound(y,fs);
            end
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            if app.CMajorCheckBox.Value
                [y,fs] = audioread(app.CMajorFiles(2));
                sound(y,fs);
            else 
                [y,fs] = audioread(app.GMajorFiles(2));
                sound(y,fs);
            end
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            if app.CMajorCheckBox.Value
                [y,fs] = audioread(app.CMajorFiles(3));
                sound(y,fs);
            else 
                [y,fs] = audioread(app.GMajorFiles(3));
                sound(y,fs);
            end
        end

        % Button pushed function: Button4
        function Button4Pushed(app, event)
            if app.CMajorCheckBox.Value
                [y,fs] = audioread(app.CMajorFiles(4));
                sound(y,fs);
            else 
                [y,fs] = audioread(app.GMajorFiles(4));
                sound(y,fs);
            end
        end

        % Button pushed function: Button5
        function Button5Pushed(app, event)
            if app.CMajorCheckBox.Value
                [y,fs] = audioread(app.CMajorFiles(5));
                sound(y,fs);
            else 
                [y,fs] = audioread(app.GMajorFiles(5));
                sound(y,fs);
            end
        end

        % Button pushed function: Button6
        function Button6Pushed(app, event)
            if app.CMajorCheckBox.Value
                [y,fs] = audioread(app.CMajorFiles(6));
                sound(y,fs);
            else 
                [y,fs] = audioread(app.GMajorFiles(6));
                sound(y,fs);
            end
        end

        % Button pushed function: Button7
        function Button7Pushed(app, event)
            if app.CMajorCheckBox.Value
                [y,fs] = audioread(app.CMajorFiles(7));
                sound(y,fs);
            else 
                [y,fs] = audioread(app.GMajorFiles(7));
                sound(y,fs);
            end
        end

        % Button pushed function: Button8
        function Button8Pushed(app, event)
            if app.CMajorCheckBox.Value
                [y,fs] = audioread(app.CMajorFiles(8));
                sound(y,fs);
            else 
                [y,fs] = audioread(app.GMajorFiles(8));
                sound(y,fs);
            end
        end

        % Button pushed function: Button9
        function Button9Pushed(app, event)
            if app.CMajorCheckBox.Value
                [y,fs] = audioread(app.CMajorFiles(9));
                sound(y,fs);
            else 
                [y,fs] = audioread(app.GMajorFiles(9));
                sound(y,fs);
            end
        end

        % Button pushed function: Button10
        function Button10Pushed(app, event)
            if app.CMajorCheckBox.Value
                [y,fs] = audioread(app.CMajorFiles(10));
                sound(y,fs);
            else 
                [y,fs] = audioread(app.GMajorFiles(10));
                sound(y,fs);
            end
        end

        % Button pushed function: Button11
        function Button11Pushed(app, event)
            if app.CMajorCheckBox.Value
                [y,fs] = audioread(app.CMajorFiles(11));
                sound(y,fs);
            else 
                [y,fs] = audioread(app.GMajorFiles(11));
                sound(y,fs);
            end
        end

        % Button pushed function: Button12
        function Button12Pushed(app, event)
            if app.CMajorCheckBox.Value
                [y,fs] = audioread(app.CMajorFiles(12));
                sound(y,fs);
            else 
                [y,fs] = audioread(app.GMajorFiles(12));
                sound(y,fs);
            end
        end

        % Button pushed function: Button13
        function Button13Pushed(app, event)
            if app.CMajorCheckBox.Value
                [y,fs] = audioread(app.CMajorFiles(13));
                sound(y,fs);
            else 
                [y,fs] = audioread(app.GMajorFiles(13));
                sound(y,fs);
            end
        end

        % Button pushed function: Button14
        function Button14Pushed(app, event)
            if app.CMajorCheckBox.Value
                [y,fs] = audioread(app.CMajorFiles(14));
                sound(y,fs);
            else 
                [y,fs] = audioread(app.GMajorFiles(14));
                sound(y,fs);
            end
        end

        % Button pushed function: Button15
        function Button15Pushed(app, event)
            if app.CMajorCheckBox.Value
                [y,fs] = audioread(app.CMajorFiles(15));
                sound(y,fs);
            else 
                [y,fs] = audioread(app.GMajorFiles(15));
                sound(y,fs);
            end
        end

        % Button pushed function: Button16
        function Button16Pushed(app, event)
            if app.CMajorCheckBox.Value
                [y,fs] = audioread(app.CMajorFiles(16));
                sound(y,fs);
            else 
                [y,fs] = audioread(app.GMajorFiles(16));
                sound(y,fs);
            end
        end

        % Button pushed function: Button17
        function Button17Pushed(app, event)
            if app.CMajorCheckBox.Value
                [y,fs] = audioread(app.CMajorFiles(17));
                sound(y,fs);
            else 
                [y,fs] = audioread(app.GMajorFiles(17));
                sound(y,fs);
            end
        end

        % Value changed function: GMajorCheckBox
        function GMajorCheckBoxValueChanged(app, event)
        if app.GMajorCheckBox.Value
           app.CMajorCheckBox.Value = false;
        else
            app.CMajorCheckBox.Value = true;
        end
            updateButtons(app);
        end

        % Value changed function: CMajorCheckBox
        function CMajorCheckBoxValueChanged(app, event)
           
        if app.GMajorCheckBox.Value
           app.CMajorCheckBox.Value = false;
        else
            app.CMajorCheckBox.Value = true;
        end
            updateButtons(app);
        end

        % Callback function
        function ButtonPushed(app, event)
            [y, fs] = audioread("711.MP3");
            app.audioplayerObj = audioplayer(y, fs);
            play(app.audioplayerObj);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if ~isempty(app.audioplayerObj) && isplaying(app.audioplayerObj)
                stop(app.audioplayerObj);
            end
        end

        % Button pushed function: AscendingButton
        function AscendingButtonPushed(app, event)
            asc_hard();
            delete(app);
        end

        % Button pushed function: AlternateButton
        function AlternateButtonPushed(app, event)
            alt_hard();
            delete(app);
        end

        % Button pushed function: DescendingButton
        function DescendingButtonPushed(app, event)
            desc_hard();
            delete(app);
        end

        % Button pushed function: TabsButton
        function TabsButtonPushed(app, event)
            TabSelectionPage();
            delete(app);
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            home();
            delete(app);
        end

        % Button pushed function: Button
        function PlayButton(app, event)
            [y, fs] = audioread("711.MP3");
            app.audioplayerObj = audioplayer(y, fs);
            play(app.audioplayerObj);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'711 - Toneejay'; ''; '1 5 5° 5° 5° '; '3° 3° 2° 2° '; '1° 1° 1° 1° '; '5 5 3° 4° 3° 2° 2° '; '3° 2° '; '1° '; '5 6 '; '5 3° '; '3 5 5° 5° 5° '; '3° 3° '; '2° 2° 2° 3° 2° '; '1° '; '5 3° 4° 3° 2° 2° 1° 1° 1° 1° '; '6 5 5 2° 3° '; '1° 7° 1°° 7° 6° 6° '; '7° '; '6° '; '3° '; '5 '; '3° 4° 3° 2° 2° 1° 1° 1° 1° '; '1° 1°° '; '6° 6° 6° 6° '; '5° '; '5 3° 4° 3° 2° 2° '; '3° 2° '; '1° '; '1° 7° 1°° '; '6° 6° 6° '; '5 3° 4° 3° 2° 2° '; '3° 2° '; '1° '; '5 5 '; '(1 3 5) 3° 3° '; '(2 4) 3° 3° '; '(1 4) 3° 3° 2° 2° 1° 1° '; '(1 3) 3° 3° '; '(2 4) 3° 3° '; '(1 4) 3° 3° 2° 2° 1° 1° 1° (1 3 1°) 2° 3° 4° (4 5°) 6° '; '7° 7° 1°° (1 4) 6° '; '5° '; '(1 3) 3° 3° '; '(2 4) 3° 3° '; '(1 4) 3° 3° 2° 2° 1° 1° '; '(1 3) '; '(1 3) '; '(2 4) '; '(2 4) '; '(1 4) '; '(1 4) '; '(1 4) '; '(1 4)'};
            app.DigitalKalimbaLabel = uilabel(app.UIFigure);
            app.DigitalKalimbaLabel.Text = 'Digital Kalimba';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @PlayButton, true);
            app.Button.Icon = 'play.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'pause.png';
            app.Button_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '__________________________________________________________________________________________________________________________________________________________________';
            app.KeyArrangementsLabel = uilabel(app.UIFigure);
            app.KeyArrangementsLabel.Text = 'Key Arrangements';
            app.KeySetsLabel = uilabel(app.UIFigure);
            app.KeySetsLabel.Text = 'Key Sets';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.TabsButton = uibutton(app.UIFigure, 'push');
            app.TabsButton.ButtonPushedFcn = createCallbackFcn(app, @TabsButtonPushed, true);
            app.TabsButton.Text = 'Tabs';
            app.Button1 = uibutton(app.UIFigure, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = 'Button1';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = 'Button3';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button4.Text = 'Button4';
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button5.Text = 'Button5';
            app.Button6 = uibutton(app.UIFigure, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @Button6Pushed, true);
            app.Button6.Text = 'Button6';
            app.Button7 = uibutton(app.UIFigure, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @Button7Pushed, true);
            app.Button7.Text = 'Button7';
            app.Button8 = uibutton(app.UIFigure, 'push');
            app.Button8.ButtonPushedFcn = createCallbackFcn(app, @Button8Pushed, true);
            app.Button8.Text = 'Button8';
            app.Button9 = uibutton(app.UIFigure, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @Button9Pushed, true);
            app.Button9.Text = 'Button9';
            app.Button10 = uibutton(app.UIFigure, 'push');
            app.Button10.ButtonPushedFcn = createCallbackFcn(app, @Button10Pushed, true);
            app.Button10.Text = 'Button10';
            app.Button11 = uibutton(app.UIFigure, 'push');
            app.Button11.ButtonPushedFcn = createCallbackFcn(app, @Button11Pushed, true);
            app.Button11.Text = 'Button11';
            app.Button12 = uibutton(app.UIFigure, 'push');
            app.Button12.ButtonPushedFcn = createCallbackFcn(app, @Button12Pushed, true);
            app.Button12.Text = 'Button12';
            app.Button13 = uibutton(app.UIFigure, 'push');
            app.Button13.ButtonPushedFcn = createCallbackFcn(app, @Button13Pushed, true);
            app.Button13.Text = 'Button13';
            app.Button14 = uibutton(app.UIFigure, 'push');
            app.Button14.ButtonPushedFcn = createCallbackFcn(app, @Button14Pushed, true);
            app.Button14.Text = 'Button14';
            app.Button15 = uibutton(app.UIFigure, 'push');
            app.Button15.ButtonPushedFcn = createCallbackFcn(app, @Button15Pushed, true);
            app.Button15.Text = 'Button15';
            app.Button16 = uibutton(app.UIFigure, 'push');
            app.Button16.ButtonPushedFcn = createCallbackFcn(app, @Button16Pushed, true);
            app.Button16.Text = 'Button16';
            app.Button17 = uibutton(app.UIFigure, 'push');
            app.Button17.ButtonPushedFcn = createCallbackFcn(app, @Button17Pushed, true);
            app.Button17.Text = 'Button17';
            app.GMajorCheckBox = uicheckbox(app.UIFigure);
            app.GMajorCheckBox.ValueChangedFcn = createCallbackFcn(app, @GMajorCheckBoxValueChanged, true);
            app.GMajorCheckBox.Text = 'G Major';
            app.CMajorCheckBox = uicheckbox(app.UIFigure);
            app.CMajorCheckBox.ValueChangedFcn = createCallbackFcn(app, @CMajorCheckBoxValueChanged, true);
            app.CMajorCheckBox.Text = 'C Major';
            app.DescendingButton = uibutton(app.UIFigure, 'push');
            app.DescendingButton.ButtonPushedFcn = createCallbackFcn(app, @DescendingButtonPushed, true);
            app.DescendingButton.Text = 'Descending';
            app.AscendingButton = uibutton(app.UIFigure, 'push');
            app.AscendingButton.ButtonPushedFcn = createCallbackFcn(app, @AscendingButtonPushed, true);
            app.AscendingButton.Text = 'Ascending';
            app.AlternateButton = uibutton(app.UIFigure, 'push');
            app.AlternateButton.ButtonPushedFcn = createCallbackFcn(app, @AlternateButtonPushed, true);
            app.AlternateButton.Text = 'Alternate';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'd';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'f';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'g';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'h';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = 'j';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'k';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'l';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'q';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = 'w';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = 'e';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = 'r';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = 't';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = 'y';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = 'u';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = 'i';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = 'o';
            app.Label_18 = uilabel(app.UIFigure);
            app.Label_18.Text = 'p';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = asc_hard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end